classdef Testy1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LensTypeListBox                 matlab.ui.control.ListBox
        LensTypeListBoxLabel            matlab.ui.control.Label
        NumberofRaysSpinner             matlab.ui.control.Spinner
        NumberofRaysSpinnerLabel        matlab.ui.control.Label
        LensRadiusSpinner               matlab.ui.control.Spinner
        LensRadiusSpinnerLabel          matlab.ui.control.Label
        ObjectDistanceEditField         matlab.ui.control.NumericEditField
        ObjectDistanceEditFieldLabel    matlab.ui.control.Label
        ObjectHeightEditField           matlab.ui.control.NumericEditField
        ObjectHeightEditFieldLabel      matlab.ui.control.Label
        ExitingAngleEditField           matlab.ui.control.NumericEditField
        MaterialIndexEditField          matlab.ui.control.NumericEditField
        MaterialIndexEditFieldLabel     matlab.ui.control.Label
        ExitingAngleEditFieldLabel      matlab.ui.control.Label
        MaterialsListBox                matlab.ui.control.ListBox
        MaterialsListBoxLabel           matlab.ui.control.Label
        AngleofIndicenceEditField       matlab.ui.control.NumericEditField
        AngleofIndicenceEditFieldLabel  matlab.ui.control.Label
        SurroundingsIndexEditField      matlab.ui.control.NumericEditField
        SurroundingsIndexEditFieldLabel  matlab.ui.control.Label
        SurroundingsListBox             matlab.ui.control.ListBox
        SurroundingsListBoxLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property 
        
        % Surrounding Refractive Indices
        Refractive_indices_Fluid = {'Air', 1.00;
                                        'Water', 1.33;
                                        'Glycerol', 1.473;
                                        'Bromine', 1.661};
        % Material Refractive Indices
        Refractive_indices_Materials = {'Plate Glass', 1.52;
                                        'Flint Glass', 1.62;
                                        'Sapphire', 1.778;
                                        'Cubic Zirconia',2.18;
                                        'Germanium', 4.05}
        % Lens Types
        LensTypes = ['Thin Lens', 'Plano-Convex'];
    end
    
    methods (Access = public)
        
        function  PlotLens(app)
            %Initial Variables
            height = app.ObjectHeightEditField.Value;
            distance = app.ObjectDistanceEditField.Value;
            radius = app.LensRadiusSpinner.Value;
            raycount = app.NumberofRaysSpinner.Value;
            n_surroundings = app.SurroundingsIndexEditField.Value;
            n_lens = app.MaterialIndexEditField.Value;
            theta_in = app.AngleofIndicenceEditField.Value;

            height_array = linspace(-height,height,raycount);

            %Initlizing the Lens:
            lens = [zeros(raycount,1) height_array'];
            %Column 1: Origin axis
            %Column 2: Y-points for where the lens is struck


            %Initializing the Rays:
            x_range = [-distance zeros];              %Starting to end points
            init_rays = [-distance*tand(theta_in) + height_array' height_array']; %The heights of each ray

            LensType = app.LensTypeListBox.Value;


            switch LensType

                case 'Thin Lens'
                    %Given the properties of the thin lens approximation, we know the
                    %following:
                    focal_point = [radius/2, 0]; %Focal prime is just negative of the focal length, so we can reuse this variable
            
                    %For creating the thin lens, we can just use a straight line. For
                    %visualization, we want this line to be longer than height_array.
                    %We also intialize this as the "origin".
                    lens = [zeros(size(height_array))' height_array'];      
                    %Column 1: Origin axis
                    %Column 2: Y-points for where the lens is struck
                                                                           
                    lens(1,2) = lens(1,2)- 2;
                    lens(end,2) = lens(end,2)+ 2;
            
                    %Now we set up the entering rays at the "plane" face.
                    x_range = linspace(-distance,0,2);
                    init_rays = height_array'.*ones(size(x_range));
            
                    %Creating the exiting rays
                    %For thin lens, we must cross the focal point. Rather than doing
                    %complex stuff, we can just construct point-slope equations.
                    start_point = init_rays(:,end);
                    x_step = linspace(0,2*focal_point(1),10);
                    m = (focal_point(2)-start_point)/(focal_point(1));
                    exit_rays = m .* x_step + height_array';


                case 'Plano-Convex'
                    %Creating the Lens Shape (Plano-Convex):
                    
                    %Column 3: X-points for where the lens is struck on the convex side
                    lens(:,3) = sqrt(radius^2 - lens(:,2).^2);
                    
                    %Find slopes of the curve:
                    Curvature = -lens(:,3)./sqrt(radius^2-lens(:,3).^2);
            
                    %Find the Angle of Incidences wrt Height Array
                    angle_incidence = pi/2-atan(Curvature);                
                    %Angle here is determined by the slope
                    angle_incidence(abs(angle_incidence)>pi) = NaN;        
                    %Any angles higher than pi would result in critical angles. We will plot them some other time.
                          
                    %Developing Snell's Law for Exiting Angles
                    angle_exiting = asin(n_lens*sin(angle_incidence)/n_surroundings);
            
                    %Correcting for bending towards focal point
                    min_angle = find(angle_exiting<=min(angle_exiting));
                    angle_exiting(min_angle:end) = - angle_exiting(min_angle:end);
            
                    %Critical Angle
                    critical_angle = asin(n_lens*sin(pi/2)/n_surroundings);
            
                    %Dealing with Critical Angle
                    angle_exiting(abs(angle_exiting)>=critical_angle) = NaN; %Ignoring these values for now.
                    %Will later deal with total internal reflection
            
                    %Setting up plotting values by creating a point-slope equation:
                    x_step = [lens(:,3) ones(size(lens(:,3)))*2.5*radius];
                    m =      atan(angle_exiting);
                    b =      height_array';
                    exit_rays = (m .* (x_step-lens(:,3)) + b);
    
                    %Plotting Results:
                    %Plotting Lens curve
                    plot(app.UIAxes,lens(:,3),lens(:,2),'c',linewidth=3);
                    hold(app.UIAxes,'on')
                    %Plotting Rays through Lens
                    plot(app.UIAxes,[lens(:,1) lens(:,3)]', init_rays,'r')
                    hold(app.UIAxes,"on")
                    %Plotting Rays Exiting Lens


            end

            %Plotting the Flat Face:
            plot(app.UIAxes,lens(:,1),lens(:,2),'c',linewidth=3);
            hold(app.UIAxes, "on")
            %Plotting Initial Rays:
            plot(app.UIAxes,x_range,init_rays,'r')
            hold(app.UIAxes,"on")

            %Final Plotting:
            plot(app.UIAxes,x_step',exit_rays','g')
            hold(app.UIAxes,"off")

            
        end
        
        function theta_out = Snells_Law(n_in,n_out,theta_in)

            %Program is focused on figuring out two things:
            %1. The Critical Angle, theta_crit of a light ray entering a material
            %2. The Exiting Angle, theta_out of a light ray leaving a material, with
                %respect to the critical angle.
            
            %Equation used:
            %1.) Snell's Law:    n_in * sin(theta_in) = n_out * sin(theta_out)
            %2.) Critical Angle: n_in * sin(theta_crit) = n_out * sin(90 degrees)
            
            %Fist, we find the critical angle:
            
            theta_crit = asin(n_out*sind(90)/n_in);
            
            %Second, we find the exiting angles depending on the critical angle:
            
            condition = (theta_in < theta_crit) + (theta_in == theta_crit) - (theta_in > theta_crit);
            
            theta_out = condition*(asin(n_in*sin(theta_in)/n_out));


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Setting up Surroundings:
            app.SurroundingsListBox.Value =       'Air';
            app.SurroundingsIndexEditField.Value = 1;
            %Setting up Materials:
            app.MaterialsListBox.Value =      'Plate Glass';
            app.MaterialIndexEditField.Value = 1.52;
            %Initializing Object Height and Distance and Lens Radius:
            app.LensRadiusSpinner.Value =     10;
            app.ObjectHeightEditField.Value = 5;
            app.LensRadiusSpinner.Value =   5;
            %Initializing the Number of Rays:
            app.NumberofRaysSpinner.Value = 5;
            %Prevent User-Editing:
            app.SurroundingsIndexEditField.Editable = 'off';
            app.MaterialIndexEditField.Editable =     'off';
            app.ExitingAngleEditField.Editable =      'off';
            app.ObjectDistanceEditField.Editable =    'off';
            app.ObjectHeightEditField.Editable =      'off';
            app.LensRadiusSpinner.Editable =          'off';
            app.NumberofRaysSpinner.Editable =        'off';
            %Setting Limits on Spinners:
            app.LensRadiusSpinner.Limits = [5 50];
            app.NumberofRaysSpinner.Limits = [5 101];
            app.NumberofRaysSpinner.Step = 2;
            
            %Run the plot command:
            PlotLens(app)


        end

        % Value changed function: SurroundingsListBox
        function SurroundingsListBoxValueChanged(app, event)
            Surroundings = app.SurroundingsListBox.Value;
            [row] = strcmp(app.Refractive_indices_Fluid(:,1),Surroundings);
            app.SurroundingsIndexEditField.Value = cell2mat(app.Refractive_indices_Fluid(row,2));
            PlotLens(app)

        end

        % Value changed function: MaterialsListBox
        function MaterialsListBoxValueChanged(app, event)
            Materials = app.MaterialsListBox.Value;
            [row] = strcmp(app.Refractive_indices_Materials(:,1),Materials);
            app.MaterialIndexEditField.Value = cell2mat(app.Refractive_indices_Materials(row,2));
            plot(app.UIAxes, [-1 app.SurroundingsIndexEditField.Value] ,[-1 app.MaterialIndexEditField.Value],'r')
            PlotLens(app)

        end

        % Value changed function: LensRadiusSpinner
        function LensRadiusSpinnerValueChanged(app, event)
            app.ObjectHeightEditField.Value = app.LensRadiusSpinner.Value;
            PlotLens(app)
            
        end

        % Value changed function: LensTypeListBox
        function LensTypeListBoxValueChanged(app, event)
            PlotLens(app)
        end

        % Value changed function: NumberofRaysSpinner
        function NumberofRaysSpinnerValueChanged(app, event)
            PlotLens(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ray Diagram')
            xlabel(app.UIAxes, 'Distance')
            ylabel(app.UIAxes, 'Height')
            zlabel(app.UIAxes, 'Z')
            app.SurroundingsListBoxLabel = uilabel(app.UIFigure);
            app.SurroundingsListBoxLabel.Text = 'Surroundings';
            app.SurroundingsListBox = uilistbox(app.UIFigure);
            app.SurroundingsListBox.Items = {'Air', 'Water', 'Glycerol', 'Bromine'};
            app.SurroundingsListBox.ValueChangedFcn = createCallbackFcn(app, @SurroundingsListBoxValueChanged, true);
            app.SurroundingsListBox.Value = 'Air';
            app.SurroundingsIndexEditFieldLabel = uilabel(app.UIFigure);
            app.SurroundingsIndexEditFieldLabel.Text = 'Surroundings Index';
            app.SurroundingsIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleofIndicenceEditFieldLabel = uilabel(app.UIFigure);
            app.AngleofIndicenceEditFieldLabel.Text = 'Angle of Indicence';
            app.AngleofIndicenceEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaterialsListBoxLabel = uilabel(app.UIFigure);
            app.MaterialsListBoxLabel.Text = 'Materials';
            app.MaterialsListBox = uilistbox(app.UIFigure);
            app.MaterialsListBox.Items = {'Plate Glass', 'Flint Glass', 'Sapphire', 'Cubic Zirconia', 'Germanium'};
            app.MaterialsListBox.ValueChangedFcn = createCallbackFcn(app, @MaterialsListBoxValueChanged, true);
            app.MaterialsListBox.Value = 'Plate Glass';
            app.ExitingAngleEditFieldLabel = uilabel(app.UIFigure);
            app.ExitingAngleEditFieldLabel.Text = 'Exiting Angle';
            app.MaterialIndexEditFieldLabel = uilabel(app.UIFigure);
            app.MaterialIndexEditFieldLabel.Text = 'Material Index';
            app.MaterialIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExitingAngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectHeightEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectHeightEditFieldLabel.Text = 'Object Height';
            app.ObjectHeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectDistanceEditFieldLabel = uilabel(app.UIFigure);
            app.ObjectDistanceEditFieldLabel.Text = 'Object Distance';
            app.ObjectDistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.ObjectDistanceEditField.Value = 10;
            app.LensRadiusSpinnerLabel = uilabel(app.UIFigure);
            app.LensRadiusSpinnerLabel.Text = 'Lens Radius';
            app.LensRadiusSpinner = uispinner(app.UIFigure);
            app.LensRadiusSpinner.ValueChangedFcn = createCallbackFcn(app, @LensRadiusSpinnerValueChanged, true);
            app.NumberofRaysSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofRaysSpinnerLabel.Text = 'Number of Rays';
            app.NumberofRaysSpinner = uispinner(app.UIFigure);
            app.NumberofRaysSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofRaysSpinnerValueChanged, true);
            app.LensTypeListBoxLabel = uilabel(app.UIFigure);
            app.LensTypeListBoxLabel.Text = 'Lens Type';
            app.LensTypeListBox = uilistbox(app.UIFigure);
            app.LensTypeListBox.Items = {'Thin Lens', 'Plano-Convex'};
            app.LensTypeListBox.ValueChangedFcn = createCallbackFcn(app, @LensTypeListBoxValueChanged, true);
            app.LensTypeListBox.Value = 'Thin Lens';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Testy1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end